classdef IzhikevichApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        membranecapacitanceCSliderLabel  matlab.ui.control.Label
        membranecapacitanceCSlider     matlab.ui.control.Slider
        restingmembranepotvrSliderLabel  matlab.ui.control.Label
        restingmembranepotvrSlider     matlab.ui.control.Slider
        spikingthresholdvtSliderLabel  matlab.ui.control.Label
        spikingthresholdvtSlider       matlab.ui.control.Slider
        recoverytimeconstaSliderLabel  matlab.ui.control.Label
        recoverytimeconstaSlider       matlab.ui.control.Slider
        spikecutoffvpeakSliderLabel    matlab.ui.control.Label
        spikecutoffvpeakSlider         matlab.ui.control.Slider
        currentflowdSliderLabel        matlab.ui.control.Label
        currentflowdSlider             matlab.ui.control.Slider
        voltageresetvalcSliderLabel    matlab.ui.control.Label
        voltageresetvalcSlider         matlab.ui.control.Slider
        recoveryvarpropertiesbSliderLabel  matlab.ui.control.Label
        recoveryvarpropertiesbSlider   matlab.ui.control.Slider
        pulsewidthmsSliderLabel        matlab.ui.control.Label
        pulsewidthmsSlider             matlab.ui.control.Slider
        pulseamplitudemVSliderLabel    matlab.ui.control.Label
        pulseamplitudemVSlider         matlab.ui.control.Slider
        StimulusPulseparametersLabel   matlab.ui.control.Label
        IzhikevichCorticalSpikingModelLabel  matlab.ui.control.Label
        MembraneparametersLabel        matlab.ui.control.Label
        CheckouttheoriginalpaperhereButton  matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        OpenLiveScriptButton           matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

% Copyright 2020 The MathWorks, Inc

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning('off')
            app.restingmembranepotvrSlider.Value = -60;
            app.spikingthresholdvtSlider.Value = -52;
            app.recoverytimeconstaSlider.Value = 0.02;
            app.recoveryvarpropertiesbSlider.Value = -1.5;
            app.voltageresetvalcSlider.Value = -40;
            app.currentflowdSlider.Value = 170;
            app.membranecapacitanceCSlider.Value = 140;
            app.spikecutoffvpeakSlider.Value = 17;
            app.pulsewidthmsSlider.Value = 0.2;
            app.pulseamplitudemVSlider.Value = 70;
            simulateIzhikevich(app)
        end

        % Value changed function: currentflowdSlider, 
        % membranecapacitanceCSlider, pulseamplitudemVSlider, 
        % pulsewidthmsSlider, recoverytimeconstaSlider, 
        % recoveryvarpropertiesbSlider, restingmembranepotvrSlider, 
        % spikecutoffvpeakSlider, spikingthresholdvtSlider, 
        % voltageresetvalcSlider
        function simulateIzhikevich(app, event)

            % unpack parameters from GUI
            params.stm.pulseWidth   = app.pulsewidthmsSlider.Value;
            params.stm.pulseAmpl    = app.pulseamplitudemVSlider.Value;
            params.stm.T            = 1000;
            params.stm.t            = 1;
            params.mem.vr           = app.restingmembranepotvrSlider.Value;
            params.mem.vt           = app.spikingthresholdvtSlider.Value;
            params.mem.a            = app.recoverytimeconstaSlider.Value;
            params.mem.b            = app.recoveryvarpropertiesbSlider.Value;
            params.mem.c            = app.voltageresetvalcSlider.Value;
            params.mem.d            = app.currentflowdSlider.Value;
            params.mem.C            = app.membranecapacitanceCSlider.Value;
            params.mem.k            = 0.7;
            params.mem.vpeak        = app.spikecutoffvpeakSlider.Value;
                        
            % initialize/calculate some parameters
            n = round(params.stm.T/params.stm.t); % simulation time steps
            I = [zeros(1,int32((1-params.stm.pulseWidth)*n)),params.stm.pulseAmpl*ones(1,int32(params.stm.pulseWidth*n))]; % DC current
            v = params.mem.vr*ones(1,n); u = 0*v; % initial values for membrane potential & recovery
            
            % simulate the model
            for i = 1:n-1
                v(i+1)=v(i)+params.stm.t*(params.mem.k*(v(i)-params.mem.vr)*(v(i)-params.mem.vt)-u(i)+I(i))/params.mem.C;  % membrane potential
                u(i+1)=u(i)+params.stm.t*params.mem.a*(params.mem.b*(v(i)-params.mem.vr)-u(i));                   % membrane rec variable
                
                % if spike occurs, reset membrane potential and recovery variable
                if v(i+1)>=params.mem.vpeak
                    v(i)= params.mem.vpeak;
                    v(i+1)=params.mem.c;
                    u(i+1)=u(i+1)+params.mem.d;
                end
            end
            
            % plot the results
            figure(app.UIFigure)
            plot(app.UIAxes,params.stm.t*(1:n),v,'k'); % plot the membrane potential
            hold (app.UIAxes,'on')
            cp = plot(app.UIAxes,params.stm.t*(1:n),I,'r');     % plot the stimulus pulse
            % show resting membrane potential and spiking threshold on the figure
            vrl = line(app.UIAxes,[0 params.stm.T],[params.mem.vr params.mem.vr],'color','b','LineStyle',':','LineWidth',1);
            vtl = line(app.UIAxes,[0 params.stm.T],[params.mem.vt params.mem.vt],'color','m','LineStyle',':','LineWidth',1);
            % plot aesthetics
            ylim(app.UIAxes,[params.mem.vr-10 max(I)+10])
            legend(app.UIAxes,[vtl,vrl,cp],{'spiking threshold','resting potential','DC current'},"Location","northwest")
            xlabel(app.UIAxes,'simulation time (ms)')
            ylabel(app.UIAxes,'membrane potential (mV)')
            hold (app.UIAxes,'off')
        end

        % Button pushed function: CheckouttheoriginalpaperhereButton
        function weblink(app, event)
            web('http://www.izhikevich.org/publications/spikes.pdf')
        end

        % Button pushed function: OpenLiveScriptButton
        function OpenLiveScriptButtonPushed(app, event)
            open('LiveScript_Izhikevich.mlx')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.membranecapacitanceCSliderLabel = uilabel(app.UIFigure);
            app.membranecapacitanceCSliderLabel.Text = 'membrane capacitance (C)';
            app.membranecapacitanceCSlider = uislider(app.UIFigure);
            app.membranecapacitanceCSlider.Limits = [0 200];
            app.membranecapacitanceCSlider.MajorTicks = [0 100 200];
            app.membranecapacitanceCSlider.ValueChangedFcn = createCallbackFcn(app, @simulateIzhikevich, true);
            app.membranecapacitanceCSlider.MinorTicks = [0 50 100 150 200];
            app.restingmembranepotvrSliderLabel = uilabel(app.UIFigure);
            app.restingmembranepotvrSliderLabel.Text = 'resting membrane pot (vr)';
            app.restingmembranepotvrSlider = uislider(app.UIFigure);
            app.restingmembranepotvrSlider.Limits = [-150 -50];
            app.restingmembranepotvrSlider.MajorTicks = [-150 -100 -50];
            app.restingmembranepotvrSlider.ValueChangedFcn = createCallbackFcn(app, @simulateIzhikevich, true);
            app.restingmembranepotvrSlider.MinorTicks = [-150 -125 -100 -75 -50];
            app.restingmembranepotvrSlider.Value = -50;
            app.spikingthresholdvtSliderLabel = uilabel(app.UIFigure);
            app.spikingthresholdvtSliderLabel.Text = 'spiking threshold (vt)';
            app.spikingthresholdvtSlider = uislider(app.UIFigure);
            app.spikingthresholdvtSlider.Limits = [-80 0];
            app.spikingthresholdvtSlider.MajorTicks = [-80 -40 0];
            app.spikingthresholdvtSlider.ValueChangedFcn = createCallbackFcn(app, @simulateIzhikevich, true);
            app.spikingthresholdvtSlider.MinorTicks = [-80 -60 -40 -20 0];
            app.recoverytimeconstaSliderLabel = uilabel(app.UIFigure);
            app.recoverytimeconstaSliderLabel.Text = 'recovery time const (a)';
            app.recoverytimeconstaSlider = uislider(app.UIFigure);
            app.recoverytimeconstaSlider.Limits = [0 0.1];
            app.recoverytimeconstaSlider.MajorTicks = [0 0.05 0.1];
            app.recoverytimeconstaSlider.ValueChangedFcn = createCallbackFcn(app, @simulateIzhikevich, true);
            app.recoverytimeconstaSlider.MinorTicks = [];
            app.spikecutoffvpeakSliderLabel = uilabel(app.UIFigure);
            app.spikecutoffvpeakSliderLabel.Text = 'spike cutoff (vpeak)';
            app.spikecutoffvpeakSlider = uislider(app.UIFigure);
            app.spikecutoffvpeakSlider.Limits = [0 70];
            app.spikecutoffvpeakSlider.MajorTicks = [0 35 70];
            app.spikecutoffvpeakSlider.ValueChangedFcn = createCallbackFcn(app, @simulateIzhikevich, true);
            app.spikecutoffvpeakSlider.MinorTicks = [];
            app.currentflowdSliderLabel = uilabel(app.UIFigure);
            app.currentflowdSliderLabel.Text = 'current flow (d)';
            app.currentflowdSlider = uislider(app.UIFigure);
            app.currentflowdSlider.Limits = [0 200];
            app.currentflowdSlider.MajorTicks = [0 100 200];
            app.currentflowdSlider.ValueChangedFcn = createCallbackFcn(app, @simulateIzhikevich, true);
            app.currentflowdSlider.MinorTicks = [];
            app.voltageresetvalcSliderLabel = uilabel(app.UIFigure);
            app.voltageresetvalcSliderLabel.Text = 'voltage reset val (c)';
            app.voltageresetvalcSlider = uislider(app.UIFigure);
            app.voltageresetvalcSlider.Limits = [-100 0];
            app.voltageresetvalcSlider.MajorTicks = [-100 -50 0];
            app.voltageresetvalcSlider.ValueChangedFcn = createCallbackFcn(app, @simulateIzhikevich, true);
            app.voltageresetvalcSlider.MinorTicks = [];
            app.recoveryvarpropertiesbSliderLabel = uilabel(app.UIFigure);
            app.recoveryvarpropertiesbSliderLabel.Text = 'recovery var properties (b)';
            app.recoveryvarpropertiesbSlider = uislider(app.UIFigure);
            app.recoveryvarpropertiesbSlider.Limits = [-4 0];
            app.recoveryvarpropertiesbSlider.MajorTicks = [-4 -2 0];
            app.recoveryvarpropertiesbSlider.ValueChangedFcn = createCallbackFcn(app, @simulateIzhikevich, true);
            app.recoveryvarpropertiesbSlider.MinorTicks = [];
            app.pulsewidthmsSliderLabel = uilabel(app.UIFigure);
            app.pulsewidthmsSliderLabel.Text = 'pulse width (ms)';
            app.pulsewidthmsSlider = uislider(app.UIFigure);
            app.pulsewidthmsSlider.Limits = [0 1];
            app.pulsewidthmsSlider.MajorTicks = [0 0.5 1];
            app.pulsewidthmsSlider.ValueChangedFcn = createCallbackFcn(app, @simulateIzhikevich, true);
            app.pulsewidthmsSlider.MinorTicks = [];
            app.pulseamplitudemVSliderLabel = uilabel(app.UIFigure);
            app.pulseamplitudemVSliderLabel.Text = 'pulse amplitude (mV)';
            app.pulseamplitudemVSlider = uislider(app.UIFigure);
            app.pulseamplitudemVSlider.MajorTicks = [0 50 100];
            app.pulseamplitudemVSlider.ValueChangedFcn = createCallbackFcn(app, @simulateIzhikevich, true);
            app.pulseamplitudemVSlider.MinorTicks = [];
            app.StimulusPulseparametersLabel = uilabel(app.UIFigure);
            app.StimulusPulseparametersLabel.Text = 'Stimulus Pulse parameters';
            app.IzhikevichCorticalSpikingModelLabel = uilabel(app.UIFigure);
            app.IzhikevichCorticalSpikingModelLabel.Text = 'Izhikevich Cortical Spiking Model';
            app.MembraneparametersLabel = uilabel(app.UIFigure);
            app.MembraneparametersLabel.Text = 'Membrane parameters';
            app.CheckouttheoriginalpaperhereButton = uibutton(app.UIFigure, 'push');
            app.CheckouttheoriginalpaperhereButton.ButtonPushedFcn = createCallbackFcn(app, @weblink, true);
            app.CheckouttheoriginalpaperhereButton.Text = 'Check out the original paper here';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Screenshot 2020-10-05 151033.png';
            app.OpenLiveScriptButton = uibutton(app.UIFigure, 'push');
            app.OpenLiveScriptButton.ButtonPushedFcn = createCallbackFcn(app, @OpenLiveScriptButtonPushed, true);
            app.OpenLiveScriptButton.Text = 'Open Live Script';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'time (ms)')
            ylabel(app.UIAxes, 'mV')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IzhikevichApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end